classdef app_gyro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        AxeZGauge       matlab.ui.control.LinearGauge
        AxeZGaugeLabel  matlab.ui.control.Label
        AxeYGauge       matlab.ui.control.LinearGauge
        AxeYGaugeLabel  matlab.ui.control.Label
        AxeXGauge       matlab.ui.control.LinearGauge
        AxeXGaugeLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        timerDataAcquisition % Description
    end
    
    methods (Access = private)
        
        function results = getAxisData(app, src, event)
            arduino = serialport("COM3", 9600);
            rawReception = readline(arduino);
            data = str2double(split(rawReception, ','));
            app.AxeXGauge.Value = data(1);
            app.AxeYGauge.Value = data(2);
            app.AxeZGauge.Value = data(3);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function getData(app)
            app.timerDataAcquisition = timer("ExecutionMode","fixedRate","Period",0.1, "BusyMode","queue","TimerFcn",@app.getAxisData)
            
            
            app.getAxisData();
         
            if strcmp(app.timerDataAcquisition.Running,"off")
                start(app.timerDataAcquisition);
             end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AxeXGaugeLabel = uilabel(app.GridLayout);
            app.AxeXGaugeLabel.Text = 'Axe X';
            app.AxeXGauge = uigauge(app.GridLayout, 'linear');
            app.AxeXGauge.Limits = [-5 5];
            app.AxeYGaugeLabel = uilabel(app.GridLayout);
            app.AxeYGaugeLabel.Text = 'Axe Y';
            app.AxeYGauge = uigauge(app.GridLayout, 'linear');
            app.AxeYGauge.Limits = [-5 5];
            app.AxeZGaugeLabel = uilabel(app.GridLayout);
            app.AxeZGaugeLabel.Text = 'Axe Z';
            app.AxeZGauge = uigauge(app.GridLayout, 'linear');
            app.AxeZGauge.Limits = [-5 5];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_gyro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @getData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end